classdef FTIR_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadNewDataButton             matlab.ui.control.Button
        ClearDataButton               matlab.ui.control.Button
        SampleVolumeLEditFieldLabel   matlab.ui.control.Label
        SampleVolumeLEditField        matlab.ui.control.NumericEditField
        SpacerSizemEditFieldLabel     matlab.ui.control.Label
        SpacerSizemEditField          matlab.ui.control.NumericEditField
        TimeIntervalsEditFieldLabel   matlab.ui.control.Label
        TimeIntervalsEditField        matlab.ui.control.NumericEditField
        GasFactorEditFieldLabel       matlab.ui.control.Label
        GasFactorEditField            matlab.ui.control.NumericEditField
        PlotSpectraButton             matlab.ui.control.Button
        CurrentdataTextAreaLabel      matlab.ui.control.Label
        CurrentdataTextArea           matlab.ui.control.TextArea
        DiffusionCoefficientTextAreaLabel  matlab.ui.control.Label
        DiffusionCoefficientTextArea  matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data FTIRexperiment % Description
    end
    
    methods (Access = private)
        
        function output = displayData(app)
            output = ["You have "+size(app.data.data,2)+" spectra";"Sample Volume is "+app.data.volume;"Spacer Size is "+app.data.pathLength;
                "Gas Factor is "+app.data.gasFactor];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            [spectra,freq] = LoadSpectra();
            app.data = FTIRexperiment(spectra,freq(:,1),0,0,0,"");
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.data = FTIRexperiment(0,0,0,0,0,"");
            app.CurrentdataTextArea.Value = "You have no data.";
            cla(app.UIAxes)
            cla(app.UIAxes2)
            app.SampleVolumeLEditField.Value = 0;
            app.SpacerSizemEditField.Value = 0;
            app.TimeIntervalsEditField.Value = 0;
            app.GasFactorEditField.Value = 0;
            app.PlotSpectraButton.Enable = false;
        end

        % Button pushed function: PlotSpectraButton
        function PlotSpectraButtonPushed(app, event)
            %find the indicies for the amount of spectra desired
            spectraIndicies = zeros(1,10);
            interval = floor(size(app.data.data,2)/10);
            for ii = 0:10-1
                spectraIndicies(ii+1) = 1+(ii*interval);
            end
            if spectraIndicies(end) ~= size(app.data.data,2)
                spectraIndicies = [spectraIndicies size(app.data.data,2)];
            end
            %baseline correction
            baseline = app.data.data - app.data.data(28375,:);
            %gas line correction
            %cd("Users/matthewliberatore/Library/CloudStorage/OneDrive-UniversityofPittsburgh/data/ftir_data/Matt/Gas Lines Ref")
            wha = load("CO2_gas_lines.mat",'gasLines');
            fixed = baseline-app.data.gasFactor.*wha.gasLines;
            plot(app.UIAxes2,app.data.freqAxis,fixed(:,spectraIndicies))
            
            %converts data in f to vector of concentration values of CO2
            %for each spectrum, generates time axis, returns a plot of
            %concentration vs time
            %syntax: plotConcOverTime(f)
            plot(app.UIAxes,timeAxis(app.data),concOverTime(app.data));
        end

        % Value changed function: SampleVolumeLEditField
        function SampleVolumeLEditFieldValueChanged(app, event)
            app.data.volume = app.SampleVolumeLEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
            
        end

        % Value changed function: SpacerSizemEditField
        function SpacerSizemEditFieldValueChanged(app, event)
            app.data.pathLength = app.SpacerSizemEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Value changed function: TimeIntervalsEditField
        function TimeIntervalsEditFieldValueChanged(app, event)
            app.data.timeInterval = app.TimeIntervalsEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Value changed function: GasFactorEditField
        function GasFactorEditFieldValueChanged(app, event)
            app.data.gasFactor = app.GasFactorEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
            PlotSpectraButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadNewDataButton = uibutton(app.UIFigure, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.SampleVolumeLEditFieldLabel = uilabel(app.UIFigure);
            app.SampleVolumeLEditFieldLabel.Text = 'Sample Volume (μL)';
            app.SampleVolumeLEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleVolumeLEditField.ValueChangedFcn = createCallbackFcn(app, @SampleVolumeLEditFieldValueChanged, true);
            app.SpacerSizemEditFieldLabel = uilabel(app.UIFigure);
            app.SpacerSizemEditFieldLabel.Text = 'Spacer Size (μm)';
            app.SpacerSizemEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpacerSizemEditField.ValueChangedFcn = createCallbackFcn(app, @SpacerSizemEditFieldValueChanged, true);
            app.TimeIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeIntervalsEditFieldLabel.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalsEditFieldValueChanged, true);
            app.GasFactorEditFieldLabel = uilabel(app.UIFigure);
            app.GasFactorEditFieldLabel.Text = 'Gas Factor';
            app.GasFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.GasFactorEditField.ValueChangedFcn = createCallbackFcn(app, @GasFactorEditFieldValueChanged, true);
            app.PlotSpectraButton = uibutton(app.UIFigure, 'push');
            app.PlotSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectraButtonPushed, true);
            app.PlotSpectraButton.Enable = 'off';
            app.PlotSpectraButton.Text = 'Plot Spectra';
            app.CurrentdataTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentdataTextAreaLabel.Text = 'Current data';
            app.CurrentdataTextArea = uitextarea(app.UIFigure);
            app.CurrentdataTextArea.Value = {'You have no data.'};
            app.DiffusionCoefficientTextAreaLabel = uilabel(app.UIFigure);
            app.DiffusionCoefficientTextAreaLabel.Text = 'Diffusion Coefficient';
            app.DiffusionCoefficientTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration of CO2 vs Time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'concentration (M)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FTIR Spectra of the CO2 peak')
            xlabel(app.UIAxes2, 'frequency (cm^{-1})')
            ylabel(app.UIAxes2, 'absorbance (AU)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [2290 2390];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FTIR_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end